classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AtractorLabel                 matlab.ui.control.Label
        AtractorSelection             matlab.ui.control.DropDown
        aLabel                        matlab.ui.control.Label
        a                             matlab.ui.control.NumericEditField
        bLabel                        matlab.ui.control.Label
        b                             matlab.ui.control.NumericEditField
        cLabel                        matlab.ui.control.Label
        c                             matlab.ui.control.NumericEditField
        ParametrosLabel               matlab.ui.control.Label
        CondicionesInicialesLabel     matlab.ui.control.Label
        xEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        GraficarButton                matlab.ui.control.Button
        AnimarButton                  matlab.ui.control.Button
        LimpiarButton                 matlab.ui.control.Button
        TiempoEditFieldLabel          matlab.ui.control.Label
        TiempoEditField               matlab.ui.control.NumericEditField
        ConservaranteriorSwitchLabel  matlab.ui.control.Label
        ConservaranteriorSwitch       matlab.ui.control.Switch
        TiempodeSimulacionLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            ts = [0, app.TiempoEditField.Value];
            x0 = [app.xEditField.Value app.yEditField.Value app.zEditField.Value ];
            
            p1 = app.a.Value;
            p2 = app.b.Value;
            p3 = app.c.Value;
            
            if app.AtractorSelection.Value == "Rössler"
                [t,x] = ode45(@(t,x) Rossler(t,x,p1,p2,p3), ts, x0);
            else
                [t,x] = ode45(@(t,x) Lorenz(t,x,p1,p2,p3), ts, x0);
            end
            
            plot3(app.UIAxes, x(:,1), x(:,2), x(:,3));
            xlim(app.UIAxes,[min(x(:,1)),max(x(:,1))]);
            ylim(app.UIAxes,[min(x(:,2)),max(x(:,2))]);
            zlim(app.UIAxes,[min(x(:,3)),max(x(:,3))]);
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Value changed function: ConservaranteriorSwitch
        function ConservaranteriorSwitchValueChanged(app, event)
            value = app.ConservaranteriorSwitch.Value;
            if value == "Si"
                hold(app.UIAxes, 'on')
            else
                hold(app.UIAxes, 'off')
            end
        end

        % Button pushed function: AnimarButton
        function AnimarButtonPushed(app, event)
            cla(app.UIAxes)
            hold(app.UIAxes, 'off')
            app.ConservaranteriorSwitch.Value = "No"
            ts = [0, app.TiempoEditField.Value];
            x0 = [app.xEditField.Value app.yEditField.Value app.zEditField.Value ];
            
            p1 = app.a.Value;
            p2 = app.b.Value;
            p3 = app.c.Value;
            
            if app.AtractorSelection.Value == "Rössler"
                [t,x] = ode45(@(t,x) Rossler(t,x,p1,p2,p3), ts, x0);
%                 MakeVideo(x,"rossler")
            else
                [t,x] = ode45(@(t,x) Lorenz(t,x,p1,p2,p3), ts, x0);
            end
            d = size(x);
            
            for k = 1:d(1)
                plot3(app.UIAxes, x(1:k,1),x(1:k,2),x(1:k,3));
                xlim(app.UIAxes,[min(x(:,1)),max(x(:,1))]);
                ylim(app.UIAxes,[min(x(:,2)),max(x(:,2))]);
                zlim(app.UIAxes,[min(x(:,3)),max(x(:,3))]);
                app.TiempodeSimulacionLabel.Text = join(["Tiempo de Simulacion: ", num2str(t(k))]," ")
                view(app.UIAxes, 45 + 0.1*k, 35)
                pause(0.00000001)
            end           
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AtractorLabel = uilabel(app.UIFigure);
            app.AtractorLabel.Text = 'Atractor';
            app.AtractorSelection = uidropdown(app.UIFigure);
            app.AtractorSelection.Items = {'Lorenz', 'Rössler'};
            app.AtractorSelection.Value = 'Lorenz';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'numeric');
            app.a.Value = 10;
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.b.Value = 28;
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = {'c'; ''};
            app.c = uieditfield(app.UIFigure, 'numeric');
            app.c.Value = 2.6;
            app.ParametrosLabel = uilabel(app.UIFigure);
            app.ParametrosLabel.Text = 'Parametros';
            app.CondicionesInicialesLabel = uilabel(app.UIFigure);
            app.CondicionesInicialesLabel.Text = 'Condiciones Iniciales';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = {'y'; ''};
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.AnimarButton = uibutton(app.UIFigure, 'push');
            app.AnimarButton.ButtonPushedFcn = createCallbackFcn(app, @AnimarButtonPushed, true);
            app.AnimarButton.Text = 'Animar';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.TiempoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel.Text = {'Tiempo'; ''};
            app.TiempoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEditField.Limits = [10 Inf];
            app.TiempoEditField.Value = 10;
            app.ConservaranteriorSwitchLabel = uilabel(app.UIFigure);
            app.ConservaranteriorSwitchLabel.Text = 'Conservar anterior';
            app.ConservaranteriorSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConservaranteriorSwitch.Items = {'No', 'Si'};
            app.ConservaranteriorSwitch.ValueChangedFcn = createCallbackFcn(app, @ConservaranteriorSwitchValueChanged, true);
            app.ConservaranteriorSwitch.Value = 'No';
            app.TiempodeSimulacionLabel = uilabel(app.UIFigure);
            app.TiempodeSimulacionLabel.Text = 'Tiempo de Simulacion: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end